classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DropDownFolderRef             matlab.ui.control.DropDown
        DropDownFolderRefLabel        matlab.ui.control.Label
        DropDownSubOption             matlab.ui.control.DropDown
        SubOptionLabel                matlab.ui.control.Label
        DropDownFolder                matlab.ui.control.DropDown
        FolderDropDownLabel           matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        LogTextAreaLabel              matlab.ui.control.Label
        EditFieldParam2               matlab.ui.control.NumericEditField
        Param2Label                   matlab.ui.control.Label
        EditFieldParam1               matlab.ui.control.NumericEditField
        Param1Label                   matlab.ui.control.Label
        DropDownTeknik                matlab.ui.control.DropDown
        TeknikDigunakanDropDownLabel  matlab.ui.control.Label
        ButtonProcess                 matlab.ui.control.Button
        DropDownCitra                 matlab.ui.control.DropDown
        CitraDigunakanDropDownLabel   matlab.ui.control.Label
        UIAxesRef                     matlab.ui.control.UIAxes
        UIAxesHistHasil               matlab.ui.control.UIAxes
        UIAxesHasil                   matlab.ui.control.UIAxes
        UIAxesHistAsli                matlab.ui.control.UIAxes
        UIAxesAsli                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgAsli
        imgHasil
    end
    
    methods (Access = private)
        
        function updateParameterLayout(app)
            teknik = app.DropDownTeknik.Value;
        
            % Sembunyikan semua dulu sebagai default
            cla(app.UIAxesRef);
            title(app.UIAxesRef, '');    
            xlabel(app.UIAxesRef, '');  
            ylabel(app.UIAxesRef, '');   
            app.UIAxesRef.Visible = 'off'; 
            app.DropDownSubOption.Visible = 'off';
            app.SubOptionLabel.Visible = 'off';
            app.EditFieldParam1.Visible = 'off';
            app.Param1Label.Visible = 'off';
            app.EditFieldParam2.Visible = 'off';
            app.Param2Label.Visible = 'off';
            app.DropDownFolderRef.Visible = 'off';
            app.DropDownFolderRefLabel.Visible = 'off';
        
            switch teknik
                case 'Intensity Transformation'
                    app.DropDownSubOption.Visible = 'on';
                    app.SubOptionLabel.Visible = 'on';
                    app.SubOptionLabel.Text = 'Jenis Transformasi';
                    app.DropDownSubOption.Items = {'Negative', 'Log', 'Gamma', 'Contrast Stretch'};
        
                    jenis = app.DropDownSubOption.Value;
                    switch jenis
                        case 'Gamma'
                            app.EditFieldParam1.Visible = 'on';
                            app.Param1Label.Visible = 'on';
                            app.Param1Label.Text = 'Gamma';
                            app.EditFieldParam1.Value = 1;
                        case 'Contrast Stretch'
                            app.EditFieldParam1.Visible = 'on';
                            app.Param1Label.Visible = 'on';
                            app.Param1Label.Text = 'Low In';
                            app.EditFieldParam2.Visible = 'on';
                            app.Param2Label.Visible = 'on';
                            app.Param2Label.Text = 'High In';
                            app.EditFieldParam2.Value = 255; 
                        % Negative & Log: nggak butuh parameter tambahan
                    end
        
                case 'Median'
                    app.EditFieldParam1.Visible = 'on';
                    app.Param1Label.Visible = 'on';
                    app.Param1Label.Text = 'Window Size';
                    app.EditFieldParam1.Value = 3;
        
                case 'Histogram Matching'
                    app.UIAxesRef.Visible = 'on';
                    app.DropDownFolderRef.Visible = 'on';
                    app.DropDownFolderRefLabel.Visible = 'on';
                    app.DropDownFolderRefLabel.Text = 'Folder Referensi';
                
                    app.DropDownSubOption.Visible = 'on';
                    app.SubOptionLabel.Visible = 'on';
                    app.SubOptionLabel.Text = 'Citra Referensi';
                
                    % Isi DropDownFolderRef sama kayak DropDownFolder (semua folder dataset)
                    app.DropDownFolderRef.Items = app.DropDownFolder.Items;
                
                    % Kalau belum pernah dipilih, default ke folder yang sama dulu
                    if isempty(app.DropDownFolderRef.Value)
                        app.DropDownFolderRef.Value = app.DropDownFolder.Value;
                    end
                
                    % Isi daftar citra sesuai folder referensi yang aktif
                    folderRefTerpilih = app.DropDownFolderRef.Value;
                    lokasiApp = fileparts(which('Main'));
                    projectRoot = fullfile(lokasiApp, '..', '..');
                    folderPath = fullfile(projectRoot, 'data', folderRefTerpilih);
                
                    daftarFile = [dir(fullfile(folderPath, '*.jpg')); ...
                                  dir(fullfile(folderPath, '*.png')); ...
                                  dir(fullfile(folderPath, '*.bmp'))];
                    app.DropDownSubOption.Items = {daftarFile.name};
                    app.DropDownSubOption.Value = daftarFile(1).name;
                    tampilkanCitraRef(app);
        
                % Blur & Histogram Equalization: nggak butuh parameter tambahan
            end
        end
        
        function tampilkanCitraRef(app)
            namaFileRef = app.DropDownSubOption.Value;
            folderRefTerpilih = app.DropDownFolderRef.Value;
        
            if isempty(namaFileRef) || isempty(folderRefTerpilih)
                return;
            end
        
            lokasiApp = fileparts(which('Main'));
            projectRoot = fullfile(lokasiApp, '..', '..');
            fullPathRef = fullfile(projectRoot, 'data', folderRefTerpilih, namaFileRef);
        
            if ~isfile(fullPathRef)
                return;
            end
        
            imgRef = imread(fullPathRef);
            imshow(imgRef, 'Parent', app.UIAxesRef);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Tambahkan folder src/functions dan src/utils ke path,
            % biar manualHist, imageFeatures, dll bisa terdeteksi
            lokasiApp = fileparts(mfilename('fullpath'));
            projectRoot = fullfile(lokasiApp, '..', '..');
            addpath(fullfile(projectRoot, 'src', 'functions'));
            addpath(fullfile(projectRoot, 'src', 'utils'));

            % Auto-load folder pertama biar DropDownCitra langsung keisi
            DropDownFolderValueChanged(app, []);
            updateParameterLayout(app);
        end

        % Value changed function: DropDownCitra
        function DropDownCitraValueChanged(app, event)
            namaFile = app.DropDownCitra.Value;
            folderTerpilih = app.DropDownFolder.Value;
        
            lokasiApp = fileparts(which('Main'));
            projectRoot = fullfile(lokasiApp, '..', '..');
            fullPath = fullfile(projectRoot, 'data', folderTerpilih, namaFile);
        
            app.imgAsli = imread(fullPath);
            if size(app.imgAsli, 3) == 3
                R = app.imgAsli(:,:,1);
                G = app.imgAsli(:,:,2);
                B = app.imgAsli(:,:,3);
                if isequal(R, G) && isequal(G, B)
                    app.imgAsli = R;   % ambil 1 kanal aja, buang 2 lainnya
                end
            end
        
            imshow(app.imgAsli, 'Parent', app.UIAxesAsli);
        
            counts = manualHist(app.imgAsli);
            if size(app.imgAsli, 3) == 1
                % Grayscale: 1 warna abu-abu aja
                bar(app.UIAxesHistAsli, 0:255, counts, 'FaceColor', [0.5 0.5 0.5]);
            else
                % RGB: overlay 3 garis warna R, G, B dalam 1 axes yang sama
                plot(app.UIAxesHistAsli, 0:255, counts(:,1), 'r', 'LineWidth', 1); % Merah
                hold(app.UIAxesHistAsli, 'on');
                plot(app.UIAxesHistAsli, 0:255, counts(:,2), 'g', 'LineWidth', 1); % Hijau
                plot(app.UIAxesHistAsli, 0:255, counts(:,3), 'b', 'LineWidth', 1); % Biru
                hold(app.UIAxesHistAsli, 'off');
                legend(app.UIAxesHistAsli, {'R', 'G', 'B'});
            end
        
            fitur = imageFeatures(app.imgAsli);
            app.TextArea.Value = sprintf('Mean: %.2f | Std: %.2f | Entropy: %.3f', ...
                fitur.meanVal(1), fitur.stdVal(1), fitur.entropy(1));
            
        end

        % Value changed function: DropDownFolder
        function DropDownFolderValueChanged(app, event)
            folderTerpilih = app.DropDownFolder.Value;
        
            % Cari lokasi folder project secara otomatis, TIDAK bergantung
            % di mana Current Folder MATLAB lagi berada.
            lokasiApp = fileparts(which('Main'));          % folder tempat Main.mlapp berada (src/gui)
            projectRoot = fullfile(lokasiApp, '..', '..');  % naik 2 folder ke root project
        
            folderPath = fullfile(projectRoot, 'data', folderTerpilih);
        
            % Debug: tampilkan path yang dicoba, biar ketauan kalau masih salah
            disp(['Mencoba folder: ' folderPath]);
        
            if ~isfolder(folderPath)
                uialert(app.UIFigure, ['Folder tidak ditemukan: ' folderPath], 'Error');
                return;
            end
        
            daftarFile = [dir(fullfile(folderPath, '*.jpg')); ...
                          dir(fullfile(folderPath, '*.png')); ...
                          dir(fullfile(folderPath, '*.bmp'))];
        
            if isempty(daftarFile)
                uialert(app.UIFigure, ['Tidak ada gambar di folder: ' folderPath], 'Peringatan');
                app.DropDownCitra.Items = {'(kosong)'};
                return;
            end
        
            namaFileSaja = {daftarFile.name};
            app.DropDownCitra.Items = namaFileSaja;
            app.DropDownCitra.Value = namaFileSaja{1};
            DropDownCitraValueChanged(app, []);
        end

        % Button pushed function: ButtonProcess
        function ButtonProcessPushed(app, event)
            if isempty(app.imgAsli)
                uialert(app.UIFigure, 'Pilih citra dulu sebelum Process.', 'Peringatan');
                return;
            end
        
            teknik = app.DropDownTeknik.Value;
        
            switch teknik
                case 'Intensity Transformation'
                    jenis = app.DropDownSubOption.Value;
                    switch jenis
                        case 'Negative'
                            app.imgHasil = intensityTransform(app.imgAsli, 'negative', []);
                        case 'Log'
                            app.imgHasil = intensityTransform(app.imgAsli, 'log', struct());
                        case 'Gamma'
                            gammaVal = app.EditFieldParam1.Value;
                            app.imgHasil = intensityTransform(app.imgAsli, 'gamma', struct('gamma', gammaVal));
                        case 'Contrast Stretch'
                            lowIn = app.EditFieldParam1.Value;
                            highIn = app.EditFieldParam2.Value;
                            app.imgHasil = intensityTransform(app.imgAsli, 'contrast', struct('lowIn', lowIn, 'highIn', highIn));
                    end
        
                case 'Blur'
                    kernel = ones(3,3) / 9;
                    app.imgHasil = convFilter(app.imgAsli, kernel);
        
                case 'Median'
                    windowSize = round(app.EditFieldParam1.Value);
                    if mod(windowSize, 2) == 0
                        windowSize = windowSize + 1;   % otomatis bulatkan ke ganjil terdekat
                        app.EditFieldParam1.Value = windowSize;
                    end
                    app.imgHasil = manualMedFilter(app.imgAsli, windowSize);
        
                case 'Histogram Equalization'
                    app.imgHasil = manualHistEQ(app.imgAsli);
        
                case 'Histogram Matching'
                    namaFileRef = app.DropDownSubOption.Value;
                    folderTerpilih = app.DropDownFolderRef.Value;
                    lokasiApp = fileparts(which('Main'));
                    projectRoot = fullfile(lokasiApp, '..', '..');
                    fullPathRef = fullfile(projectRoot, 'data', folderTerpilih, namaFileRef);
                    imgRef = imread(fullPathRef);
                    if size(imgRef, 3) == 3
                        R = imgRef(:,:,1);
                        G = imgRef(:,:,2);
                        B = imgRef(:,:,3);
                        if isequal(R, G) && isequal(G, B)
                            imgRef = R;
                        end
                    end
                    app.imgHasil = manualHistMatch(app.imgAsli, imgRef);
            end
        
            % Tampilkan citra hasil
            imshow(app.imgHasil, 'Parent', app.UIAxesHasil);
        
            % Tampilkan histogram hasil
            countsHasil = manualHist(app.imgHasil);
            if size(app.imgHasil, 3) == 1
                bar(app.UIAxesHistHasil, 0:255, countsHasil, 'FaceColor', [0.5 0.5 0.5]);
            else
                plot(app.UIAxesHistHasil, 0:255, countsHasil(:,1), 'r', 'LineWidth', 1);
                hold(app.UIAxesHistHasil, 'on');
                plot(app.UIAxesHistHasil, 0:255, countsHasil(:,2), 'g', 'LineWidth', 1);
                plot(app.UIAxesHistHasil, 0:255, countsHasil(:,3), 'b', 'LineWidth', 1);
                hold(app.UIAxesHistHasil, 'off');
                legend(app.UIAxesHistHasil, {'R', 'G', 'B'});
            end
        
            % Tampilkan fitur hasil
            fiturHasil = imageFeatures(app.imgHasil);
            teksSebelum = app.TextArea.Value;
            teksSesudah = sprintf('Hasil (%s) -> Mean: %.2f | Std: %.2f | Entropy: %.3f', ...
                teknik, fiturHasil.meanVal(1), fiturHasil.stdVal(1), fiturHasil.entropy(1));
            app.TextArea.Value = [teksSebelum; {teksSesudah}];
        end

        % Value changed function: DropDownTeknik
        function DropDownTeknikValueChanged(app, event)
            updateParameterLayout(app);
        end

        % Value changed function: DropDownSubOption
        function DropDownSubOptionValueChanged(app, event)
            if strcmp(app.DropDownTeknik.Value, 'Intensity Transformation')
                updateParameterLayout(app);
            elseif strcmp(app.DropDownTeknik.Value, 'Histogram Matching')
                tampilkanCitraRef(app);
            end
            % Contrast Stretch butuh 2 parameter (lowIn, highIn) - pakai EditField3
            % dan mungkin 1 field lagi kalau mau, atau input manual dulu
        end

        % Value changed function: DropDownFolderRef
        function DropDownFolderRefValueChanged(app, event)
            % Update daftar citra referensi sesuai folder referensi yang baru dipilih
            folderRefTerpilih = app.DropDownFolderRef.Value;
            lokasiApp = fileparts(which('Main'));
            projectRoot = fullfile(lokasiApp, '..', '..');
            folderPath = fullfile(projectRoot, 'data', folderRefTerpilih);
        
            daftarFile = [dir(fullfile(folderPath, '*.jpg')); ...
                          dir(fullfile(folderPath, '*.png')); ...
                          dir(fullfile(folderPath, '*.bmp'))];
            app.DropDownSubOption.Items = {daftarFile.name};
            app.DropDownSubOption.Value = daftarFile(1).name;

            tampilkanCitraRef(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesAsli = uiaxes(app.UIFigure);
            title(app.UIAxesAsli, 'Gambar Asli')
            xlabel(app.UIAxesAsli, 'X')
            ylabel(app.UIAxesAsli, 'Y')
            zlabel(app.UIAxesAsli, 'Z')
            app.UIAxesHistAsli = uiaxes(app.UIFigure);
            title(app.UIAxesHistAsli, 'Hist Asli')
            xlabel(app.UIAxesHistAsli, 'X')
            ylabel(app.UIAxesHistAsli, 'Y')
            zlabel(app.UIAxesHistAsli, 'Z')
            app.UIAxesHasil = uiaxes(app.UIFigure);
            title(app.UIAxesHasil, 'Gambar Hasil')
            xlabel(app.UIAxesHasil, 'X')
            ylabel(app.UIAxesHasil, 'Y')
            zlabel(app.UIAxesHasil, 'Z')
            app.UIAxesHistHasil = uiaxes(app.UIFigure);
            title(app.UIAxesHistHasil, 'Hist Hasil')
            xlabel(app.UIAxesHistHasil, 'X')
            ylabel(app.UIAxesHistHasil, 'Y')
            zlabel(app.UIAxesHistHasil, 'Z')
            app.UIAxesRef = uiaxes(app.UIFigure);
            title(app.UIAxesRef, 'Citra Referensi')
            xlabel(app.UIAxesRef, 'X')
            ylabel(app.UIAxesRef, 'Y')
            zlabel(app.UIAxesRef, 'Z')
            app.CitraDigunakanDropDownLabel = uilabel(app.UIFigure);
            app.CitraDigunakanDropDownLabel.Text = 'Citra Digunakan';
            app.DropDownCitra = uidropdown(app.UIFigure);
            app.DropDownCitra.ValueChangedFcn = createCallbackFcn(app, @DropDownCitraValueChanged, true);
            app.ButtonProcess = uibutton(app.UIFigure, 'push');
            app.ButtonProcess.ButtonPushedFcn = createCallbackFcn(app, @ButtonProcessPushed, true);
            app.ButtonProcess.Text = 'Process';
            app.TeknikDigunakanDropDownLabel = uilabel(app.UIFigure);
            app.TeknikDigunakanDropDownLabel.Text = 'Teknik Digunakan';
            app.DropDownTeknik = uidropdown(app.UIFigure);
            app.DropDownTeknik.Items = {'Intensity Transformation', 'Blur', 'Median', 'Histogram Equalization', 'Histogram Matching'};
            app.DropDownTeknik.ValueChangedFcn = createCallbackFcn(app, @DropDownTeknikValueChanged, true);
            app.DropDownTeknik.Value = 'Intensity Transformation';
            app.Param1Label = uilabel(app.UIFigure);
            app.Param1Label.Text = 'Param1Label';
            app.EditFieldParam1 = uieditfield(app.UIFigure, 'numeric');
            app.Param2Label = uilabel(app.UIFigure);
            app.Param2Label.Text = 'Param2Label';
            app.EditFieldParam2 = uieditfield(app.UIFigure, 'numeric');
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.FolderDropDownLabel = uilabel(app.UIFigure);
            app.FolderDropDownLabel.Text = 'Folder';
            app.DropDownFolder = uidropdown(app.UIFigure);
            app.DropDownFolder.Items = {'1. Histogram Citra', '2. Kasus 1', '3. Kasus 2', '4. Kasus 3', '5. Kasus 4'};
            app.DropDownFolder.ValueChangedFcn = createCallbackFcn(app, @DropDownFolderValueChanged, true);
            app.DropDownFolder.Value = '1. Histogram Citra';
            app.SubOptionLabel = uilabel(app.UIFigure);
            app.SubOptionLabel.Text = 'SubOptionLabel';
            app.DropDownSubOption = uidropdown(app.UIFigure);
            app.DropDownSubOption.Items = {'Negative', 'Log', 'Gamma', 'Contrast Stretch'};
            app.DropDownSubOption.ValueChangedFcn = createCallbackFcn(app, @DropDownSubOptionValueChanged, true);
            app.DropDownSubOption.Value = 'Negative';
            app.DropDownFolderRefLabel = uilabel(app.UIFigure);
            app.DropDownFolderRefLabel.Text = 'Folder Referensi';
            app.DropDownFolderRef = uidropdown(app.UIFigure);
            app.DropDownFolderRef.ValueChangedFcn = createCallbackFcn(app, @DropDownFolderRefValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end